classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end


    properties (Access = private)
        wedgeHeader %cell of each name of wedge struct field
    end

    properties (Access = public)
        wedges % struct full of wedges
        constraints % struct with portfolio constraints
        strat %struct with all strategy info
    end

    methods (Access = public)
    
        function [] = readWedges(app, file_name)
            %readWedges get wedges info into struct
            %   Each wedge has it's ID as it's position in struct, as well as ID
            %   each element of header is in field
            fid = fopen(file_name); 
            line = fgetl(fid);
            header = strsplit(line, ';'); %split at semicolons
            app.wedges = struct(); %make struct
            for indField = 1:numel(header)
                app.wedges.(header{indField}) = NaN; %make all the fields w/ dummy val
            end
            line = fgetl(fid);
            while(~isnumeric(line))
                parts = strsplit(line, ';');
                for indF = 1:numel(header)
                    if(~isnan(str2double(parts{indF})))
                        parts{indF} = str2double(parts{indF}); %conver to doubles
                    end %if
                    app.wedges(parts{1}).(header{indF})= parts{indF};
                        %add each part into the struct, ID is also it's position
        
                end %for loop
                line = fgetl(fid);
            end% while loop
            app.wedgeHeader = header; %assign to global
        end
        
        function [] = readConstraints(app, file_name)
            %readConstraints Put 'em in a struct
            %   each sector is a field, with limits as double value
            fid = fopen(file_name);%open file
            line = fgetl(fid);
            header = strsplit(line); %split at semicolons
            app.constraints = struct(); %make struct
            line = fgetl(fid);
            while(~isnumeric(line))
                parts = strsplit(line);
                parts{2} = str2double(parts{2}); %conver to doubles
                app.constraints.(parts{1})= parts{2};
                    %add each part into the struct, ID is also it's position
                line = fgetl(fid);
            end% while loop
        end
        
        function [] = readStrat(app, file_name)
            fid = fopen(file_name);%open file
            line = fgetl(fid);
            header = strsplit(line); %split at semicolons
            app.strat = struct(); %make struct
            for indField = 1:numel(header)
                app.strat.(header{indField}) = NaN; %make all the fields w/ dummy val
            end
            line = fgetl(fid);
            indSpot = 1; %increment through each spot
            while(~isnumeric(line))
                parts = strsplit(line);
                for indF = 1:numel(header)
                    if(~isnan(str2double(parts{indF})))
                        parts{indF} = str2double(parts{indF}); %conver to doubles
                    end %if
                    app.strat(indSpot).(header{indF})= parts{indF};
                        %add each part into the struct, ID is also it's position
        
                end %for loop
                line = fgetl(fid);
                indSpot = indSpot + 1; %next spot
            end% while loop
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            numFiles = size(varargin); %how many files? should be 1, 2, or 3
            if(numFiles > 0)%we have at least 1?
                readWedges(app,varargin{1}); %assign variables, assigns to prop inside
            end
            if(numFiles > 1) %we have at least 2
                readConstraints(app,varargin{2});
            end
            if(numFiles == 3) %we have three files
                app.strategy = readStrategy(app,varargin{3});
            end
            
            
            %Code to test if structs fillign right
%             tempCell = struct2cell(app.wedges);
%             cell(:,:) = tempCell(:,1,:);
%             app.UITable.Data = cell;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end